classdef ClasificadorPlantas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label                      matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        ClasificarButton           matlab.ui.control.Button
        PetalWidthEditField        matlab.ui.control.NumericEditField
        PetalWidthEditFieldLabel   matlab.ui.control.Label
        PetalLengthEditField       matlab.ui.control.NumericEditField
        PetalLengthEditFieldLabel  matlab.ui.control.Label
        SepalWidthEditField        matlab.ui.control.NumericEditField
        SepalWidthEditFieldLabel   matlab.ui.control.Label
        SepalLengthEditField       matlab.ui.control.NumericEditField
        SepalLengthEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        var = load("trainedModel.mat") % Loading the trained model

        %Load the images
        setosa = imread("setosa.png");
        versicolor = imread("versicolor.png");
        virginica = imread("virginica.png");
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)

            trainedModel = app.var.trainedModel;
            %Creamos la tabla para leer la información
            % Define the size: 0 rows, 4 variables
            sz = [0 4];

            % Define the variable types for each column
            varTypes = ["double", "double", "double", "double"];

            % Define the variable names
            varNames = ["SepalLength", "SepalWidth", "PetalLength", "PetalWidth"];

            % Create the empty table
            irisData = table('Size', sz, 'VariableTypes', varTypes, 'VariableNames', varNames);
            irisData(1,:) = {app.SepalLengthEditField.Value,...
                app.SepalWidthEditField.Value,...
                app.PetalLengthEditField.Value,...
                app.PetalWidthEditField.Value};
            %Predict the species
            [app.Label.Text] = trainedModel.predictFcn(irisData);

            %Change the picture
            if app.Label.Text == "setosa"
                app.Image.ImageSource = app.setosa;
            elseif app.Label.Text == "versicolor"
                app.Image.ImageSource = app.versicolor;
            elseif app.Label.Text == "virginica"
            else
                app.Image.ImageSource = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SepalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SepalLengthEditFieldLabel.Text = 'Sepal Length';
            app.SepalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SepalLengthEditField.Value = 5.7;
            app.SepalWidthEditFieldLabel = uilabel(app.UIFigure);
            app.SepalWidthEditFieldLabel.Text = 'Sepal Width';
            app.SepalWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SepalWidthEditField.Value = 2.8;
            app.PetalLengthEditFieldLabel = uilabel(app.UIFigure);
            app.PetalLengthEditFieldLabel.Text = 'Petal Length';
            app.PetalLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PetalLengthEditField.Value = 4.1;
            app.PetalWidthEditFieldLabel = uilabel(app.UIFigure);
            app.PetalWidthEditFieldLabel.Text = 'Petal Width';
            app.PetalWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PetalWidthEditField.Value = 1.3;
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ml-quiz-question5-question-mark.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ClasificadorPlantas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end